classdef MusicApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        ChoseMachineLearningMethodDropDownLabel  matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        PleasepresstheplaybuttontoseethesignalLabel  matlab.ui.control.Label
        MUSICGENREAPPLabel      matlab.ui.control.Label
        Accuracy                matlab.ui.control.Label
        OurSuggestions          matlab.ui.control.Label
        Genre                   matlab.ui.control.Label
        PredictButton           matlab.ui.control.Button
        SelectaMusicFileButton  matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_6                matlab.ui.control.Button
        ChoseMachineLearningMethodDropDown  matlab.ui.control.DropDown
        Button_7                matlab.ui.control.Button
        SignalAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

  % Models
  %Properties for storing trained machine learning models
        model_Fine_Tree
        model_Medium_Tree
        model_Coarse_Tree
        model_Linear_SVM
        model_Quadratic_SVM
        model_Fine_KNN
        model_Medium_KNN
        model_Coarse_KNN
        model_Boosted_Trees
        model_Narrow_Neural_Network
        model_Medium_Neural_Network

        audio_File_Path      % Selected Audio File           
        player              % For playing the selected audio
        is_Audio_Paused  = true;    % If paused true, else false
        is_Audio_Playing = false;        % If audio is playing true, else false
        can_Play_Audio = false;  % If the audio can be played true, else false

      
        % Songs to Suggest
        %Predefined song lists for each genre
        % yenileri
        blues       = ["Sweet Home Chicago - Robert Johnson", "The Thrill Is Gone - B.B. King", "Cross Road Blues - Robert Johnson", "Hellhound on My Trail - Robert Johnson", "I'm Tore Down - Freddie King", "I Can't Quit You Baby - Willie Dixon", "Red House - Jimi Hendrix", "Boom Boom - John Lee Hooker", "Hoochie Coochie Man - Muddy Waters", "I’m Your Hoochie Coochie Man - Muddy Waters"];
        classical   = ["Clair de Lune - Claude Debussy", "Canon in D Major - Johann Pachelbel", "Für Elise - Ludwig van Beethoven", "Symphony No. 5 - Ludwig van Beethoven", "Ave Maria - Franz Schubert", "Piano Concerto No. 21 - Wolfgang Amadeus Mozart", "Eine kleine Nachtmusik - Wolfgang Amadeus Mozart", "Hungarian Rhapsody No. 2 - Franz Liszt", "Toccata and Fugue in D Minor - Johann Sebastian Bach", "Moonlight Sonata - Ludwig van Beethoven"];
        country     = ["Jolene - Dolly Parton", "The Gambler - Kenny Rogers", "Take Me Home, Country Roads - John Denver", "Before He Cheats - Carrie Underwood", "Man! I Feel Like A Woman! - Shania Twain", "Tennessee Whiskey - Chris Stapleton", "Life Is a Highway - Tom Cochrane", "Friends in Low Places - Garth Brooks", "Chicken Fried - Zac Brown Band", "I Walk the Line - Johnny Cash"];
        disco       = ["Dancing Queen - ABBA", "Hot Stuff - Donna Summer", "Le Freak - Chic", "Don't Stop 'Til You Get Enough - Michael Jackson", "Funkytown - Lipps Inc.", "I Love the Nightlife - Alicia Bridges", "Get Down On It - Kool & the Gang", "I Will Survive - Gloria Gaynor", "Stayin' Alive - Bee Gees", "Superfreak - Rick James"];
        hiphop      = ["Lose Yourself - Eminem", "HUMBLE. - Kendrick Lamar", "Juicy - The Notorious B.I.G.", "Sicko Mode - Travis Scott", "Hotline Bling - Drake", "God's Plan - Drake", "All of the Lights - Kanye West", "The Real Slim Shady - Eminem", "Nuthin' But a 'G' Thang - Dr. Dre, Snoop Dogg", "Money Trees - Kendrick Lamar ft. Jay Rock"];
        
    end
    
    methods (Access = private)
        
         %Function to reset player button states
         %invert= false when playing,true when stopping
    function resetPlayerButtons(app, invert)
        app.PauseButton.Text = "Pause";
        if invert
            app.PauseButton.Enable = 'on';
            app.PlayButton.Enable = 'off';
            app.StopButton.Enable = 'on';
        else
            app.PauseButton.Enable = 'off';
            app.PlayButton.Enable = 'on';
            app.StopButton.Enable = 'off';
        end
    end

       % Reset genre, suggestion, and accuracy displays to their initial state
    function resetDisplay(app)
        app.Genre.Text = "Genre";
        app.OurSuggestions.Text = "Our Suggestions";
        app.Accuracy.Text = "Accuracy";
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load trained models from file
        load ClassificationLearner.mat 'Fine_Tree' 'Medium_Tree' 'Coarse_Tree' ...
            'Linear_SVM' 'Quadratic_SVM' 'Fine_KNN' 'Medium_KNN' 'Coarse_KNN' ...
            'Boosted_Trees' 'Narrow_Neural_Network' 'Medium_Neural_Network';

        % Assign models to app properties
        app.model_Fine_Tree = Fine_Tree;
        app.model_Medium_Tree = Medium_Tree;
        app.model_Coarse_Tree = Coarse_Tree;
        app.model_Linear_SVM = Linear_SVM;
        app.model_Quadratic_SVM = Quadratic_SVM;
        app.model_Fine_KNN = Fine_KNN;
        app.model_Medium_KNN = Medium_KNN;
        app.model_Coarse_KNN = Coarse_KNN;
        app.model_Boosted_Trees = Boosted_Trees;
        app.model_Narrow_Neural_Network = Narrow_Neural_Network;
        app.model_Medium_Neural_Network = Medium_Neural_Network;
        
        end

        % Button pushed function: SelectaMusicFileButton
        function SelectaMusicFileButtonPushed(app, event)
        %Handles selecting a music file.

            % Stop any ongoing playback and reset states
        if app.can_Play_Audio && app.is_Audio_Playing
            stop(app.player);
        end

        app.SelectaMusicFileButton.Text = "Select a Music File";
        app.is_Audio_Paused = true;
        app.is_Audio_Playing = false;
        app.can_Play_Audio = false;

        resetPlayerButtons(app, false);
        resetDisplay(app);

        % Open file selection dialog
        [fileName, pathName] = uigetfile('*.wav');
        figure(app.UIFigure); % Ensure the app window stays active


        % Construct full file path
        app.audio_File_Path = fullfile(pathName, fileName);

        % Verify the file extension
        [~, ~, ext] = fileparts(app.audio_File_Path);
        if ~strcmp(ext, '.wav')
            % Show error message for invalid file type
            errorBox = msgbox("Invalid File Type (Should be '.wav')", "Error", "error");
            figure(app.UIFigure); % Keep app window active
            figure(errorBox);
        else
            % Update button text with selected file name (without extension)
            app.SelectaMusicFileButton.Text = erase(fileName, '.wav');

            % Enable audio playback functionality
            app.can_Play_Audio = true;
        end
    
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
              
    % Ensure a file is selected before playing
    if ~app.can_Play_Audio
        errorBox = msgbox("Please select a music file first.", "Error", "error");
        figure(app.UIFigure); % Keep the application window active.
        figure(errorBox); % Bring the error message to the foreground.
        return; %Stop operations
    end

    % Is the music file playing?
    if ~app.is_Audio_Playing
        % Load the audio file and create an audioplayer object.
        [audioData, sampleRate] = audioread(app.audio_File_Path);

        app.player = audioplayer(audioData, sampleRate);
        [audioData, sampleRate] = audioread(app.audio_File_Path);
        t = (0:length(audioData)-1)/sampleRate; % Calculate the time axis
        plot(app.SignalAxes, t, audioData); % Plot the signal
        xlabel(app.SignalAxes, 'Time (s)');
        ylabel(app.SignalAxes, 'Amplitude');
        title(app.SignalAxes, 'Audio Signal');


        app.player = audioplayer(audioData, sampleRate);

        %Update button states to indicate playback has started
        resetPlayerButtons(app, false);

        % Play the music
        play(app.player);

        % Update playback status flags
        app.is_Audio_Paused = false;
        app.is_Audio_Playing = true;

        % Update button states to indicate playback is ongoing
        resetPlayerButtons(app, true);
    end

        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            % Toggle between pause and resume functionality
    if app.can_Play_Audio
        if app.is_Audio_Paused
            % Resume playback
            resume(app.player);
            app.PauseButton.Text = "Pause";
        else
            % Pause playback
            pause(app.player);
            app.PauseButton.Text = "Resume";
        end
        % Toggle the paused state
        app.is_Audio_Paused = ~app.is_Audio_Paused;
    end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             % Stop playback when audio is selected and currently playing
             if app.can_Play_Audio && app.is_Audio_Playing
                 % Stop the audio player
                 stop(app.player);

                 % Reset playback state flags
                 app.is_Audio_Paused = true;
                 app.is_Audio_Playing = false;

                 % Update button states to reflect stopped state
                 resetPlayerButtons(app, false);
             end
        end

        % Value changed function: ChoseMachineLearningMethodDropDown
        function ChoseMachineLearningMethodDropDownValueChanged(app, event)
            % Allow switching models without reselecting the audio file
            if app.can_Play_Audio
                % Reset display to clear previous results
                resetDisplay(app);
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)

            % Validate if an audio file is selected
            if ~app.can_Play_Audio
                uialert(app.UIFigure, 'Please select a valid audio file first.', 'Error', 'Icon', 'error');
                return;
            end

            % Validate if a machine learning method is selected
            if strcmp(app.ChoseMachineLearningMethodDropDown.Value, 'Select a Method')
                uialert(app.UIFigure, 'Please select a machine learning method first.', 'Error', 'Icon', 'error');
                return;
            end

            % Read and preprocess the audio file
            [y, ~] = audioread(app.audio_File_Path);
            yProcessed = abs(spectrogram(y, kaiser(2048, 128), 120, 16, 1E3, 'yaxis'));
            yProcessed = yProcessed(:);
            audioTable = []; % Table to hold processed audio data

            % Truncate or pad data to required length
            if length(yProcessed) >= 3087
                yProcessed = yProcessed(1:3087);
                audioTable = [audioTable yProcessed];
            else
                uialert(app.UIFigure, 'Audio file is too short for analysis.', 'Error', 'Icon', 'error');
                return;
            end

            % Get the selected model and accuracy
            modelStr = app.ChoseMachineLearningMethodDropDown.Value;
            [model, accuracy] = app.selectModelAndAccuracy(modelStr);

            % Predict the genre
            pred = model.predictFcn(audioTable);

            % Map prediction to genre and suggestions
            [genre, suggestions] = app.mapPredictionToGenre(pred);

            % Update UI with genre
            app.Genre.Text = strcat("Genre: ", genre);

            % Update UI with accuracy
            app.Accuracy.Text = strcat("Accuracy: %", accuracy);

            % Display suggestions
            app.OurSuggestions.Text = app.generateSuggestions(suggestions);
        end

        % Helper function to select model and accuracy
        function [model, accuracy] = selectModelAndAccuracy(app, modelStr)
            switch modelStr
                case "Fine Tree"
                    model = app.model_Fine_Tree;
                    accuracy = "36.6";
                case "Medium Tree"
                    model = app.model_Medium_Tree;
                    accuracy = "38.8";
                case "Coarse Tree"
                    model = app.model_Coarse_Tree;
                    accuracy = "40.2";
                case "Linear SVM"
                    model = app.model_Linear_SVM;
                    accuracy = "58.0";
                case "Quadratic SVM (Best Accuracy)"
                    model = app.model_Quadratic_SVM;
                    accuracy = "59.8";
                case "Fine KNN"
                    model = app.model_Fine_KNN;
                    accuracy = "38.1";
                case "Medium KNN"
                    model = app.model_Medium_KNN;
                    accuracy = "36.3";
                case "Coarse KNN"
                    model = app.model_Coarse_KNN;
                    accuracy = "25.1";
                case "Boosted Trees"
                    model = app.model_Boosted_Trees;
                    accuracy = "49.9";
                case "Narrow Neural Network"
                    model = app.model_Narrow_Neural_Network;
                    accuracy = "53.5";
                case "Medium Neural Network"
                    model = app.model_Medium_Neural_Network;
                    accuracy = "50.1";
                otherwise
                    error("Invalid model selection");
            end
        end

        % Helper function to map prediction to genre and suggestions
        function [genre, suggestions] = mapPredictionToGenre(app, pred)
            switch pred
                case 1
                    genre = 'Blues';
                    suggestions = app.blues;
                case 2
                    genre = 'Classical';
                    suggestions = app.classical;
                case 3
                    genre = 'Country';
                    suggestions = app.country;
                case 4
                    genre = 'Disco';
                    suggestions = app.disco;
                case 5
                    genre = 'Hiphop';
                    suggestions = app.hiphop;
                otherwise
                    genre = 'Unknown';
                    suggestions = [];
            end
        end

        % Helper function to generate song suggestions
        function randomSongs = generateSuggestions(~, suggestions)
            randomSongs = ""; % Initialize for displaying suggestions
            for i = 1:3 % Generate three suggestions
                if isempty(suggestions)
                    break;
                end
                randomSong = randsample(suggestions, 1); % Randomly select a song
                suggestions(suggestions == randomSong) = []; % Remove selected song
                randomSongs = strcat(randomSongs, sprintf("%d. %s\n", i, randomSong)); % Append to list
            end
            randomSongs = compose(randomSongs); % Format for multiline display


        end

        % Callback function
        function UIFigureCloseRequest(app, event)
            if app.can_Play_Audio && app.is_Audio_Playing % to stop audio if playing
                stop(app.player);
            end
            
            delete(app) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SignalAxes = uiaxes(app.GridLayout);
            title(app.SignalAxes, 'Audio Signal')
            xlabel(app.SignalAxes, 'Time(s)')
            ylabel(app.SignalAxes, 'Amplitude')
            zlabel(app.SignalAxes, 'Z')
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.Text = '';
            app.ChoseMachineLearningMethodDropDown = uidropdown(app.GridLayout);
            app.ChoseMachineLearningMethodDropDown.Items = {'Fine Tree', 'Medium Tree', 'Coarse Tree', 'Linear SVM', 'Quadratic SVM (Best Accuracy)', 'Fine KNN', 'Medium KNN', 'Coarse KNN', 'Boosted Trees', 'Narrow Neural Network', 'Medium Neural Network'};
            app.ChoseMachineLearningMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoseMachineLearningMethodDropDownValueChanged, true);
            app.ChoseMachineLearningMethodDropDown.Value = 'Fine Tree';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.Text = '';
            app.SelectaMusicFileButton = uibutton(app.GridLayout, 'push');
            app.SelectaMusicFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaMusicFileButtonPushed, true);
            app.SelectaMusicFileButton.Icon = fullfile(pathToMLAPP, 'Icons', 'folder.png');
            app.SelectaMusicFileButton.Text = 'Select a Music File';
            app.PredictButton = uibutton(app.GridLayout, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Genre = uilabel(app.GridLayout);
            app.Genre.Text = 'Genre';
            app.OurSuggestions = uilabel(app.GridLayout);
            app.OurSuggestions.Text = 'Our Suggestion';
            app.Accuracy = uilabel(app.GridLayout);
            app.Accuracy.Text = 'Accuracy';
            app.MUSICGENREAPPLabel = uilabel(app.GridLayout);
            app.MUSICGENREAPPLabel.Text = ' ~~~~~~~~~~~ MUSIC GENRE APP ~~~~~~~~~~~';
            app.PleasepresstheplaybuttontoseethesignalLabel = uilabel(app.GridLayout);
            app.PleasepresstheplaybuttontoseethesignalLabel.Text = '!!! Please press the play button to see the signal.';
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Nota-icon.png');
            app.PlayButton.Text = '';
            app.PauseButton = uibutton(app.GridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'Icons', 'pause-icon.png');
            app.PauseButton.Text = '';
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Stop-icon.png');
            app.StopButton.Text = '';
            app.ChoseMachineLearningMethodDropDownLabel = uilabel(app.GridLayout);
            app.ChoseMachineLearningMethodDropDownLabel.Text = 'Chose Machine Learning Method:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusicApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end